classdef ReverberantTime < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        InstructionsMenu                matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        RawsignalTab                    matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        RoomparametersPanel             matlab.ui.container.Panel
        Partitionaream2EditField        matlab.ui.control.NumericEditField
        Partitionaream2EditFieldLabel   matlab.ui.control.Label
        Receivingroomvolumem3EditField  matlab.ui.control.NumericEditField
        Receivingroomvolumem3EditFieldLabel  matlab.ui.control.Label
        ReceivingroomenergyleveldBEditField  matlab.ui.control.NumericEditField
        ReceivingroomenergyleveldBLabel  matlab.ui.control.Label
        SourceroomenergyleveldBEditField  matlab.ui.control.NumericEditField
        SourceroomenergyleveldBEditFieldLabel  matlab.ui.control.Label
        ResultsPanel                    matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        DatainterpretationguidanceLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ApparentsoundreductionindexLabel  matlab.ui.control.Label
        UITableApparentSoundReductionIndexRw  matlab.ui.control.Table
        AnalysissettingsPanel           matlab.ui.container.Panel
        AnalysismethodDropDown          matlab.ui.control.DropDown
        AnalysismethodLabel             matlab.ui.control.Label
        AveragingtimeformovingSPLEditField  matlab.ui.control.NumericEditField
        AveragingtimeformovingSPLEditFieldLabel  matlab.ui.control.Label
        TimeforbackgroundnoisesEditField  matlab.ui.control.NumericEditField
        TimeforbackgroundnoisesEditFieldLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        SelectfilesforanalysisLabel     matlab.ui.control.Label
        UITableAnalysisFiles            matlab.ui.control.Table
        ProcessfilesButton              matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        ReverberanttimeTab              matlab.ui.container.Tab
        RT11bandTab                     matlab.ui.container.Tab
        RT13octavebandTab               matlab.ui.container.Tab
        GridLayout19                    matlab.ui.container.GridLayout
        ReverberationtimesLabel         matlab.ui.control.Label
        UITableOneThirdOctaveRT         matlab.ui.control.Table
        EndSlider                       matlab.ui.control.Slider
        EndSliderLabel                  matlab.ui.control.Label
        StartSlider                     matlab.ui.control.Slider
        StartSliderLabel                matlab.ui.control.Label
        MicrophoneDropDown              matlab.ui.control.DropDown
        MicrophoneDropDownLabel         matlab.ui.control.Label
        octavebandDropDown              matlab.ui.control.DropDown
        octavebandDropDownLabel         matlab.ui.control.Label
        AnalysisfileDropDown            matlab.ui.control.DropDown
        AnalysisfileDropDownLabel       matlab.ui.control.Label
        UIAxesEDCBI                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        

        otfbSound
        oofbSound
        fileData
        dataStruct
        xLines
        xLinesBI = gobjects(1,2);
        slopeLines
        slopeLinesBIAuto
        slopeLinesBIManual
        data
        lookupGraphObjs
        lookupTableObjs
        lookupTableBIObjs
        lookupIntLim
    end
    
    methods (Access = public)
        
        
        function DisplayUIAlert(app, Title, Message)
            uialert(app.UIFigure, Message, Title);
        end
        
        function PlotDynamicRangeHorizontalLines(app, fileIdx, centerBandIdx, micIdx)
            %
            dynRangeLabel = {'-5 dB', '-25 dB', '-35 dB'};
            yline(app.UIAxesEDCBI, [-5, -25, -35], '-', dynRangeLabel, "Interpreter", "none");
        end

        function PlotVerticalLines(app)
            %
            startValue = app.StartSlider.Value;
            endValue = app.EndSlider.Value;
            app.xLinesBI(1) = xline(app.UIAxesEDCBI, startValue);
            app.xLinesBI(2) = xline(app.UIAxesEDCBI, endValue);
        end

function PlotDecayCurvesBI(app, fileIdx, centerBandIdx, micIdx)
    
    sigLength = size(app.dataStruct(fileIdx).filteredSig(centerBandIdx).EDCBILogNorm, 1);
    if ~micIdx
        % PlotDecayCurvesBI plots decay curves obtained by Schroeder BI, and vertical lines for the dynamic range T30 
        BILogNorm = app.dataStruct(fileIdx).filteredSig(centerBandIdx).EDCBILogNorm;
        plot(app.UIAxesEDCBI, app.dataStruct(1).timeVector(1:sigLength), BILogNorm);
        startIndex = app.dataStruct(fileIdx).filteredSig(centerBandIdx).startIndexT30;
        endIndex = app.dataStruct(fileIdx).filteredSig(centerBandIdx).endIndexT30;
        micLabel = app.dataStruct(fileIdx).sensorNames;
    else
        BILogNorm = app.dataStruct(fileIdx).filteredSig(centerBandIdx).EDCBILogNorm(:, micIdx);
        plot(app.UIAxesEDCBI, app.dataStruct(1).timeVector(1:sigLength), BILogNorm);
        startIndex = app.dataStruct(fileIdx).filteredSig(centerBandIdx).startIndexT30(micIdx);
        endIndex = app.dataStruct(fileIdx).filteredSig(centerBandIdx).endIndexT30(micIdx);
        micLabel = app.dataStruct(fileIdx).sensorNames{micIdx};
    end

    %% Fitting line with least squares method within the dynamic range
    % Initialisation/short names for variables
    numMics = numel(app.dataStruct(fileIdx).sensorNames);
    timeVector = app.dataStruct(fileIdx).timeVector;
    fitCoeffs = zeros([numMics 2]); % *?* % linear fit is size 1x2
    
    %% Looping over microphone signals
    % Getting coefficients for the fitted line and evaluating it
    fitLine = cell(1, numMics);    
    for j = 1 : size(BILogNorm, 2)
        fitCoeffs(j,:) = polyfit(timeVector(startIndex(j):endIndex(j), 1), ...
           BILogNorm(startIndex(j):endIndex(j), j), 1);
        fitLine{j} = polyval(fitCoeffs(j,:), timeVector(startIndex(j):endIndex(j), 1));
        app.slopeLinesBIAuto = plot(app.UIAxesEDCBI, timeVector(startIndex(j):endIndex(j), 1), fitLine{j});
    end  
    
    clear fitLine fitCoeffs % Every filtered signal (i loop) has different size, therefore clear is necessary

    % Update legends
    legend(app.UIAxesEDCBI, micLabel, "Interpreter", "none");

    
end
        
function CalculateRT(app, startTime, endTime)                    
    % *?* To do, microphone choice
    % Short names
    timeVector = app.dataStruct(app.AnalysisfileDropDown.Value).timeVector;
    fileIdx = app.AnalysisfileDropDown.Value;
    filterIdx = app.octavebandDropDown.Value;
    micIdx = app.MicrophoneDropDown.Value;
    numMics = numel(app.dataStruct(fileIdx).sensorNames);

    EDCBI = app.dataStruct(fileIdx).filteredSig(filterIdx).EDCBILogNorm;
    % Odrediti indekse za pocetak i kraj
    [~, startIndex] = min(abs(startTime - timeVector));
    [~, endIndex] = min(abs(endTime - timeVector));
    assert(~isempty([startIndex, endIndex]), "Indexes not found");

    [polyCoeffs, polyCurve]= CurveFit(app, timeVector, EDCBI, [startIndex; endIndex], 1);
    
    if isempty(app.slopeLinesBIManual) | any(~isvalid(app.slopeLinesBIManual))
        app.slopeLinesBIManual = plot(app.UIAxesEDCBI, timeVector(startIndex:endIndex, 1), [polyCurve{:}]);
    else
        app.xLinesBI(1).Value = startTime;
        app.xLinesBI(2).Value = endTime;
        for j = 1 : size(EDCBI, 2)
            set(app.slopeLinesBIManual(j), "XData", timeVector(startIndex:endIndex, 1)', ...
                "YData", polyCurve{j});
        end
        
    end

    manualCoeffs = cellfun(@(x)x(1), polyCoeffs);
    app.dataStruct(fileIdx).filteredSig(filterIdx).manualRT = -60 ./ manualCoeffs;
end
        
        function SPL = CalculateSPL(~, soundPressure)
            pRef = 2e-5; % [Pa]
            % CalculateSPL 
            SPL = 20 * log10(sqrt(mean(soundPressure.^2))./pRef);
        end
        
        function movingSPL = CalculateMovingSPL(app, soundPressure, samplingRate)
            pRef = 2e-5; % [Pa]
            % CalculateSPL calculates sound pressure level
            avgTime = app.AveragingtimeformovingSPLEditField.Value; % Number of samples for averaging the RMS;
            numSamplesForAvg = samplingRate * avgTime;
            movingSPL = 20 * log10(sqrt(movmean(soundPressure.^2, [numSamplesForAvg, 0])./pRef));
        end
        
        function [startIdx, endIdx] = GetIdxDynamicRangeT30(~, signal)
            % CalculateDynRangeT30 returns indices of the signal that
            % correspond to dynamic range 5-35 dB below the peak amplitude
            % signal is an array with units in dB
            signalMax = max(signal, [], 1);
            [~, startIdx] = min(abs(signalMax - signal - 5), [], 1);
            [~, endIdx] = min(abs(signalMax - signal - 35), [], 1);
        end

        function [startIdx, endIdx] = GetIdxDynamicRangeT20(~, signal)
            % CalculateDynRangeT30 returns indices of the signal that
            % correspond to dynamic range 5-35 dB below the peak amplitude
            % signal is an array with units in dB
            signalMax = max(signal, [], 1);
            [~, startIdx] = min(abs(signalMax - signal - 5), [], 1);
            [~, endIdx] = min(abs(signalMax - signal - 25), [], 1);
        end
        
        function PlotLegends(app)
            legString = cell(1,size(app.dataStruct(1).soundPressure, 2));
            for mm = 1 : numel(legString)
                legString{mm} = sprintf('Microphone %d', mm);
            end
            
            for i = 1:size(app.lookupGraphObjs,1)
                if ~isempty(app.lookupGraphObjs(i,3).Children)
                    legend(app.lookupGraphObjs(i,3), legString); % Decay curves
                end
                if ~isempty(app.lookupGraphObjs(i,4).Children)
                    legend(app.lookupGraphObjs(i,4), legString); % Decay curve extracts
                end
            end
            
            for jj = 2:size(app.lookupGraphObjs,1)
                if ~isempty(app.lookupGraphObjs(jj,5).Children)
                    legend(app.lookupGraphObjs(jj,5), legString); % Energy time curve BI
                end
            end
        end
        
        function [fileName, fullFilePath] = GetFileList(~, folderPath)
            %
            if isfolder(folderPath)
                fileStruct = dir(folderPath);
                fileStruct(1:2) = []; % remove non-file entries
            else
                error("*?* to do");
            end
            
            % Variables initialisation
            numFiles = numel(fileStruct);
            fullFilePath = cell(numFiles,1);
            fileName = cell(numFiles,1);
            for i = 1 : numFiles
                fileName{i} = fileStruct(i).name;
                fullFilePath{i} = fullfile(fileStruct(i).folder, fileStruct(i).name);
            end
        end
        
        function UpdateSliderComponents(app, fileIdx)
            startRange = app.dataStruct(fileIdx).timeVector(1);
            endRange = app.dataStruct(fileIdx).timeVector(end);
            app.StartSlider.Limits = [startRange, endRange];       
            app.EndSlider.Limits = [startRange, endRange];
            app.StartSlider.Value = startRange;       
            app.EndSlider.Value = endRange;
        end
        
        function [polyCoeffs, polyCurve] = CurveFit(app, x, y, indices, polyDegree)
            % Really cool stuff
            xCols = size(x, 2); 
            yCols = size(y, 2); 
            indicesCols = size(indices, 2);
        
            % expand x and indices to size of y column-wise to simplify code
            if ~isequal(xCols, yCols)
                x = repmat(x, [1, yCols]);
            end
            if isequal(indicesCols, 1)
                indices = repmat(indices, [1, yCols]);
            end
        
            % Initialize
            polyCoeffs = cell(1, yCols);
            polyCurve = cell(1, yCols);
            
            if numel(indices) == yCols % indices is 1 column
                for i = 1 : yCols
                    polyCoeffs{i} = zeros(1, indices(2));
                end
            elseif indicesCols == yCols % indices have size(y,2) columns
                for i = 1 : yCols
                    polyCoeffs{i} = zeros(1, indices(2, i));
                end 
            end
        
            % Calculate
            for j = 1 : yCols
                polyCoeffs{j} = polyfit(x(indices(1, j):indices(2, j), j), ...
                    y(indices(1, j):indices(2, j), j), polyDegree);
                polyCurve{j} = polyval(polyCoeffs{j}, x(indices(1, j):indices(2, j), j));
            end
        
        end
        
        function UpdateUITableRTResults(app)
            fileIdx = app.AnalysisfileDropDown.Value;
            fracOctavBands = {app.dataStruct(fileIdx).filteredSig(:).centralFreq}';
            RT30 = cell2mat({app.dataStruct(fileIdx).filteredSig(:).autoRT30}');
            RT20 = cell2mat({app.dataStruct(fileIdx).filteredSig(:).autoRT20}');
            manualRT = cell2mat({app.dataStruct(fileIdx).filteredSig(:).manualRT}');
            app.UITableOneThirdOctaveRT.Data = table(fracOctavBands, RT30, RT20, manualRT);
        end

        function UpdateUITableApparentSoundReductionIndex(app)
            fileIdx = app.AnalysisfileDropDown.Value;    
            fracOctavBands = {app.dataStruct(fileIdx).filteredSig(:).centralFreq}';
            manualRT = cell2mat({app.dataStruct(fileIdx).filteredSig(:).manualRT}');
            levelDifference = app.SourceroomenergyleveldBEditField.Value - app.ReceivingroomenergyleveldBEditField.Value;
            partitionArea = app.Partitionaream2EditField.Value;
            RT30 = app.UITableOneThirdOctaveRT.Data.RT30;
            RT20 = app.UITableOneThirdOctaveRT.Data.RT20;
            c = 343; % speed of sound in air [m/s]
            roomVolume = app.Receivingroomvolumem3EditField.Value;
            eqApsAreaRT30 = 55.2 * roomVolume / c ./ RT30;
            eqApsAreaRT20 = 55.2 * roomVolume / c ./ RT20;
            eqApsAreaManual = 55.2 * roomVolume / c ./ manualRT;
            RwT30 = levelDifference + 10*log10(partitionArea./eqApsAreaRT30);
            RwT20 = levelDifference + 10*log10(partitionArea./eqApsAreaRT20);
            RwManual = levelDifference + 10*log10(partitionArea./eqApsAreaManual);
            app.UITableApparentSoundReductionIndexRw.Data = table(fracOctavBands, RwT30, RwT20, RwManual);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %% This code runs when the app building has been complete
        fprintf("Application loaded\n");

        % Populate stuff
        %

        % Default behaviour
        hold(app.UIAxesEDCBI, "on");
    
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % This function sets the path of the selected file in the
            % EditField component
            [fileName, filePath] = uigetfile("*.*", "Select files for analysis", "MultiSelect", "on");
            if ischar(fileName) % Single file
                 app.UITableAnalysisFiles.Data = [cellstr(fileName), cellstr(filePath)];
            elseif iscell(fileName) % Multiple files
                if isrow(fileName)
                    fileName = fileName';
                end
                app.UITableAnalysisFiles.Data = [fileName, repmat(cellstr(filePath), [size(fileName)])];
            else
                rawDataErrorMsg = "The file has not been selected";
                rawDataErrorTitle = "Operation cancelled by user";
                DisplayUIAlert(app, rawDataErrorTitle, rawDataErrorMsg);  
            end
            drawnow; % app loses focus when uigetfile is used
            figure(app.UIFigure);
            % Update dropdown
            if ~isempty(app.UITableAnalysisFiles.Data)
                app.AnalysisfileDropDown.Items = cellstr(fileName);
                app.AnalysisfileDropDown.ItemsData = 1:size(fileName, 1);
            end
        end

        % Button pushed function: ProcessfilesButton
        function ProcessfilesButtonPushed(app, event)
            % *?* Check for valid files
            fprintf("Analysis started.\n");
            % Variables initialisation
            numFiles = size(app.UITableAnalysisFiles.Data, 1);
            fullFilePath = cell(numFiles, 1);
            fileName = cell(numFiles, 1);

            for i = 1 : numFiles
                % Creating path for every file
                fullFilePath{i} = fullfile(app.UITableAnalysisFiles.Data{i, 2}, app.UITableAnalysisFiles.Data{i, 1});
                [~, fileName{i}] = fileparts(fullFilePath{i});
                if isempty(app.fileData)
                    app.fileData = lvm_import(fullFilePath{i}, 0);
                else
                    app.fileData(i) = lvm_import(fullFilePath{i}, 0);
                end

                % Reading signal information
                numChannels = app.fileData(i).Segment1.num_channels; 
                samplingRate = 1/app.fileData(i).Segment1.Delta_X(1);
                numSamples = app.fileData(i).Segment1.num_samples(1); % Number of samples for a single channel
                app.dataStruct(i).sensorNames = app.fileData(i).Segment1.column_labels;

                % Update microphone dropdown
                app.MicrophoneDropDown.Items = ["All", app.dataStruct(i).sensorNames{:}];
                app.MicrophoneDropDown.ItemsData = 0 : numel(app.MicrophoneDropDown.Items) - 1;

                % Create 1/1 and 1/3 octave band (just once)
                if i == 1
                    minFreqSoundOT = 50;
                    maxFreqSoundOT = 5500;
                    app.otfbSound = octaveFilterBank("1/3 octave", "SampleRate", samplingRate, ...
                        "FrequencyRange", [minFreqSoundOT, maxFreqSoundOT], "FilterOrder", 2);
                    % Update dropdown
                    app.octavebandDropDown.Items = string(app.otfbSound.getCenterFrequencies);
                    app.octavebandDropDown.ItemsData = 1:numel(app.octavebandDropDown.Items);
                    
                    minFreqSoundOO = 120;
                    maxFreqSoundOO = 4100;
                    app.oofbSound = octaveFilterBank("1 octave", "SampleRate", samplingRate, ...
                        "FrequencyRange", [minFreqSoundOO, maxFreqSoundOO], "FilterOrder", 2);
                end

                % Reading raw signal
                app.dataStruct(i).soundPressure = app.fileData(i).Segment1.data(:,1:numChannels); % in [Pa]
                app.dataStruct(i).timeVector = 1/samplingRate.*(1:numSamples)'; % vrijeme za svaki uzorak % krece od nule ili od timeVector?
                [app.dataStruct(i).soundPressureMax, app.dataStruct(i).soundPressureMaxInd] = max(app.dataStruct(i).soundPressure, [], 1);           

                % Calculating SPRMS and SPL
                app.dataStruct(i).SPL = CalculateSPL(app, app.dataStruct(i).soundPressure);
                app.dataStruct(i).movingSPL = CalculateMovingSPL(app, app.dataStruct(i).soundPressure, samplingRate);
                app.dataStruct(i).SPLNormalised = app.dataStruct(i).SPL./max(app.dataStruct(i).SPL, [], 1);
                app.dataStruct(i).movingSPLNormalised = app.dataStruct(i).movingSPL./max(app.dataStruct(i).movingSPL, [], 1);
% %                 SPLSection =  reshape(app.dataStruct(1).filteredSig(1).soundPressureRMS, 1250, 2, []); % 1250x2x41
% %                 avgSPLSection = reshape(mean(SPLSection, 1), [], 2);
            
            
                %% Filter and calculate the wanted parameters for each filtered signal
                % Filter the sound pressure
                filteredSoundPressureOT = app.otfbSound(app.dataStruct(i).soundPressure);
                filteredSoundPressureOO = app.oofbSound(app.dataStruct(i).soundPressure);

                % Init
                app.dataStruct(i).filteredSig(i) = struct();
                
                % Calculating SPL, moving SPL, normalized SPL, normalised
                % energy time curve (ETC)
                for j = 1 : size(filteredSoundPressureOT, 2)
                    app.dataStruct(i).filteredSig(j).soundPressure = reshape(filteredSoundPressureOT(:, j, :), size(filteredSoundPressureOT, 1), []);
                    app.dataStruct(i).filteredSig(j).SPL = CalculateSPL(app, app.dataStruct(i).filteredSig(j).soundPressure);
                    app.dataStruct(i).filteredSig(j).movingSPL = CalculateMovingSPL(app, app.dataStruct(i).filteredSig(j).soundPressure, samplingRate);
                    app.dataStruct(i).filteredSig(j).SPLLogNorm = app.dataStruct(i).filteredSig(j).movingSPL./max(app.dataStruct(i).filteredSig(j).movingSPL, [], 1);
                    
    %                 % Calculating the dynamic range for obtaining dataStruct
    %                 Only signal in DB
    %                 [app.dataStruct(i).filteredSig(i).intStartInd, app.dataStruct(i).filteredSig(i).intEndInd] = ...
    %                     CalculateDynRangeT30(app, app.dataStruct(i).filteredSig(i).soundPressure);
                    % Reference: https://dsp.stackexchange.com/questions/17121/calculation-of-reverberation-time-rt60-from-the-impulse-response
                    
                    % Energy time curve
                    app.dataStruct(i).filteredSig(j).analyticSig = hilbert(app.dataStruct(i).filteredSig(j).movingSPL);
                    app.dataStruct(i).filteredSig(j).envelopeSig = abs(app.dataStruct(i).filteredSig(j).analyticSig);
                    app.dataStruct(i).filteredSig(j).energyTimeCurveLogNorm = 20*log10(app.dataStruct(i).filteredSig(j).envelopeSig./max(app.dataStruct(i).filteredSig(j).envelopeSig,[], 1));
    
                    % Applying lowpass filter to envelope
%                     numSamplesForAvg = app.AveragingtimeformovingSPLEditField.Value;
%                     app.dataStruct(i).filteredSig(j).energyTimeCurveFiltLog = 20*log10(movmean(app.dataStruct(i).filteredSig(j).envelopeSig , [numSamplesForAvg 0], 1) ...
%                         ./max(app.dataStruct(i).filteredSig(j).envelopeSig,[], 1));
                        
                    % Backward integration of Room Impulse Response
                    % with the t from EditField component
                    backgroundNoiseTimeEst = app.TimeforbackgroundnoisesEditField.Value;
                    timeVector = app.dataStruct(i).timeVector;
                    [~, minIndex] = min(abs(backgroundNoiseTimeEst-timeVector)); % finds index for the time in EditField component
                    
                    if backgroundNoiseTimeEst % integration with specific t
                        app.dataStruct(i).filteredSig(j).EDCBILogNorm = flipud(10*log10(cumsum(app.dataStruct(i).filteredSig(j).soundPressure(minIndex:-1:1,:).^2) ...
                        ./sum(app.dataStruct(i).filteredSig(j).soundPressure(1:minIndex,:).^2)));
                    else % integration throughout the whole signal
                        app.dataStruct(i).filteredSig(j).EDCBILogNorm = flipud(10*log10(cumsum(app.dataStruct(i).filteredSig(j).soundPressure(end:-1:1,:).^2) ...
                        ./sum(app.dataStruct(i).filteredSig(j).soundPressure(1:end,:).^2)));
                    end
                    
                    % Identification of filteredSig and corresponding central
                    % frequency
                    otfbSoundCentFreqs = app.otfbSound.getCenterFrequencies;
                    app.dataStruct(i).filteredSig(j).centralFreq = otfbSoundCentFreqs(j);
                    
                    % Getting indices for the dynamic range
                    [app.dataStruct(i).filteredSig(j).startIndexT30, app.dataStruct(i).filteredSig(j).endIndexT30] = ...
            GetIdxDynamicRangeT30(app, app.dataStruct(i).filteredSig(j).EDCBILogNorm);
                    [app.dataStruct(i).filteredSig(j).startIndexT20, app.dataStruct(i).filteredSig(j).endIndexT20] = ...
            GetIdxDynamicRangeT20(app, app.dataStruct(i).filteredSig(j).EDCBILogNorm);
                    
                    polyCoeffsT30 = CurveFit(app, app.dataStruct(i).timeVector, app.dataStruct(i).filteredSig(j).EDCBILogNorm, ...
                        [app.dataStruct(i).filteredSig(j).startIndexT30;  app.dataStruct(i).filteredSig(j).endIndexT30], 1);
                    coeffsT30 = cellfun(@(x)x(1), polyCoeffsT30);
                    polyCoeffsT20 = CurveFit(app, app.dataStruct(i).timeVector, app.dataStruct(i).filteredSig(j).EDCBILogNorm, ...
                        [app.dataStruct(i).filteredSig(j).startIndexT20;  app.dataStruct(i).filteredSig(j).endIndexT20], 1);
                    coeffsT20 = cellfun(@(x)x(1), polyCoeffsT20);

                    app.dataStruct(i).filteredSig(j).autoRT30 = -60./coeffsT30;
                    app.dataStruct(i).filteredSig(j).autoRT20 = -60./coeffsT20;
                    app.dataStruct(i).filteredSig(j).manualRT = zeros(size(coeffsT20));
                end
            end
            % Update Slider component
            UpdateSliderComponents(app, app.AnalysisfileDropDown.Value);

            % Plot regular and backward integrated decay curves
            PlotVerticalLines(app);
            PlotDynamicRangeHorizontalLines(app, app.AnalysisfileDropDown.Value, app.octavebandDropDown.Value);
            PlotDecayCurvesBI(app, app.AnalysisfileDropDown.Value, app.octavebandDropDown.Value, app.MicrophoneDropDown.Value);
            
            % Update UITable result component
            UpdateUITableRTResults(app)
            UpdateUITableApparentSoundReductionIndex(app)
            fprintf("Analysis completed.\n");
            
            
            %% Plot vertical lines for each graph
%             timeVector = app.dataStruct(1).timeVector;
%             SPL = app.dataStruct(1).SPL;
%             % Plot RT
%             app.xLines(1) = xline(app.UIAxesRT, timeVector(1000), 'LineWidth', 1, 'Label', 'Start');
%             app.xLines(2) = xline(app.UIAxesRT, timeVector(end - 1000), 'LineWidth', 1, 'Label', 'End');
%             % Plot RT 250 Hz
%             app.xLines(3) = xline(app.UIAxesRT250Hz, timeVector(1000), 'LineWidth', 1, 'Label', 'Start');
%             app.xLines(4) = xline(app.UIAxesRT250Hz, timeVector(end - 1000), 'LineWidth', 1, 'Label', 'End');
%             % Plot RT 500 Hz
%             app.xLines(5) = xline(app.UIAxesRT500Hz, timeVector(1000), 'LineWidth', 1, 'Label', 'Start');
%             app.xLines(6) = xline(app.UIAxesRT500Hz, timeVector(end - 1000), 'LineWidth', 1, 'Label', 'End');
%             % Plot RT 1000 Hz
%             app.xLines(7) = xline(app.UIAxesRT1000Hz, timeVector(1000), 'LineWidth', 1, 'Label', 'Start');
%             app.xLines(8) = xline(app.UIAxesRT1000Hz, timeVector(end - 1000), 'LineWidth', 1, 'Label', 'End');
%             % Plot RT 2000 Hz
%             app.xLines(9) = xline(app.UIAxesRT2000Hz, timeVector(1000), 'LineWidth', 1, 'Label', 'Start');
%             app.xLines(10) = xline(app.UIAxesRT2000Hz, timeVector(end - 1000), 'LineWidth', 1, 'Label', 'End');
%             % Plot RT 4000 Hz
%             app.xLines(11) = xline(app.UIAxesRT4000Hz, timeVector(1000), 'LineWidth', 1, 'Label', 'Start');
%             app.xLines(12) = xline(app.UIAxesRT4000Hz, timeVector(end - 1000), 'LineWidth', 1, 'Label', 'End');
%             
%             %% Plot slope lines 
%             app.slopeLines = gobjects([6,1]);
%             app.slopeLines(1) = line(app.UIAxesRT, [timeVector(1), timeVector(end)], [SPL(1), SPL(end)]);
%             app.slopeLines(2) = line(app.UIAxesRT250Hz, [timeVector(1), timeVector(end)], [app.dataStruct(1).filteredSig(1).SPL(1), app.dataStruct(1).filteredSig(1).movingSPL(end)]);
%             app.slopeLines(3) = line(app.UIAxesRT500Hz, [timeVector(1), timeVector(end)], [app.dataStruct(1).filteredSig(2).SPL(1), app.dataStruct(1).filteredSig(2).movingSPL(end)]);
%             app.slopeLines(4) = line(app.UIAxesRT1000Hz, [timeVector(1), timeVector(end)], [app.dataStruct(1).filteredSig(3).SPL(1), app.dataStruct(1).filteredSig(3).movingSPL(end)]);
%             app.slopeLines(5) = line(app.UIAxesRT2000Hz, [timeVector(1), timeVector(end)], [app.dataStruct(1).filteredSig(4).SPL(1), app.dataStruct(1).filteredSig(4).movingSPL(end)]);
%             app.slopeLines(6) = line(app.UIAxesRT4000Hz, [timeVector(1), timeVector(end)], [app.dataStruct(1).filteredSig(5).SPL(1), app.dataStruct(1).filteredSig(5).movingSPL(end)]);
        
            %             app.slopeLines(2) = line(app.UIAxesBI, [timeVector(1), timeVector(end)], [SPL(1), SPL(end)]);

        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            % Function resets the whole UI (dataStruct, axes, tables, sliders)
            clear app.dataStruct
            cla(app.UIAxesDecayCurve);
            % *?* To do
             
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: AnalysisfileDropDown
        function AnalysisfileDropDownValueChanged(app, event)
            cla(app.UIAxesEDCBI);
            fileIdx = app.AnalysisfileDropDown.Value;
            centerBandIdx = app.octavebandDropDown.Value;
            micIdx = app.MicrophoneDropDown.Value;
            PlotDynamicRangeHorizontalLines(app, fileIdx, centerBandIdx, micIdx);
            PlotVerticalLines(app);
            PlotDecayCurvesBI(app, fileIdx, centerBandIdx, micIdx);
            UpdateUITableRTResults(app);
            UpdateUITableApparentSoundReductionIndex(app)
        end

        % Value changed function: octavebandDropDown
        function octavebandDropDownValueChanged(app, event)
            cla(app.UIAxesEDCBI);
            fileIdx = app.AnalysisfileDropDown.Value;
            centerBandIdx = app.octavebandDropDown.Value;
            micIdx = app.MicrophoneDropDown.Value;
            PlotDynamicRangeHorizontalLines(app, fileIdx, centerBandIdx, micIdx);
            PlotVerticalLines(app);
            PlotDecayCurvesBI(app, fileIdx, centerBandIdx, micIdx);
        end

        % Value changed function: MicrophoneDropDown
        function MicrophoneDropDownValueChanged(app, event)
            cla(app.UIAxesEDCBI);
            fileIdx = app.AnalysisfileDropDown.Value;
            centerBandIdx = app.octavebandDropDown.Value;
            micIdx = app.MicrophoneDropDown.Value;
            PlotDynamicRangeHorizontalLines(app, fileIdx, centerBandIdx, micIdx);
            PlotVerticalLines(app);
            PlotDecayCurvesBI(app, fileIdx, centerBandIdx, micIdx);
        end

        % Value changing function: StartSlider
        function StartSliderValueChanging(app, event)
            % Function to calculate reverberation time
            if event.Value <= app.EndSlider.Value
                CalculateRT(app, event.Value, app.EndSlider.Value)   
            end
            
        end

        % Value changing function: EndSlider
        function EndSliderValueChanging2(app, event)
            % Function to calculate reverberation time
            if event.Value >= app.StartSlider.Value
                CalculateRT(app, app.StartSlider.Value, event.Value)  
            end
        end

        % Value changed function: StartSlider
        function StartSliderValueChanged(app, event)
            UpdateUITableRTResults(app);            
            UpdateUITableApparentSoundReductionIndex(app)
        end

        % Value changed function: EndSlider
        function EndSliderValueChanged(app, event)
            UpdateUITableRTResults(app);   
            UpdateUITableApparentSoundReductionIndex(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.NewMenu = uimenu(app.Menu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.QuitMenu = uimenu(app.Menu);
            app.QuitMenu.Text = 'Quit';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.InstructionsMenu = uimenu(app.HelpMenu);
            app.InstructionsMenu.Text = 'Instructions';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.RawsignalTab = uitab(app.TabGroup);
            app.RawsignalTab.Title = 'Raw signal';
            app.GridLayout3 = uigridlayout(app.RawsignalTab);
            app.Panel = uipanel(app.GridLayout3);
            app.Panel.Title = 'Panel';
            app.BrowseButton = uibutton(app.Panel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ProcessfilesButton = uibutton(app.Panel, 'push');
            app.ProcessfilesButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessfilesButtonPushed, true);
            app.ProcessfilesButton.Text = 'Process file(s)';
            app.UITableAnalysisFiles = uitable(app.Panel);
            app.UITableAnalysisFiles.ColumnName = {'File name'; 'File path'};
            app.UITableAnalysisFiles.RowName = {};
            app.SelectfilesforanalysisLabel = uilabel(app.Panel);
            app.SelectfilesforanalysisLabel.Text = 'Select files for analysis';
            app.AnalysissettingsPanel = uipanel(app.GridLayout3);
            app.AnalysissettingsPanel.Title = 'Analysis settings';
            app.TimeforbackgroundnoisesEditFieldLabel = uilabel(app.AnalysissettingsPanel);
            app.TimeforbackgroundnoisesEditFieldLabel.Text = 'Time for background noise [s]';
            app.TimeforbackgroundnoisesEditField = uieditfield(app.AnalysissettingsPanel, 'numeric');
            app.AveragingtimeformovingSPLEditFieldLabel = uilabel(app.AnalysissettingsPanel);
            app.AveragingtimeformovingSPLEditFieldLabel.Text = 'Averaging time for moving SPL';
            app.AveragingtimeformovingSPLEditField = uieditfield(app.AnalysissettingsPanel, 'numeric');
            app.AveragingtimeformovingSPLEditField.Limits = [0 Inf];
            app.AveragingtimeformovingSPLEditField.Value = 15;
            app.AnalysismethodLabel = uilabel(app.AnalysissettingsPanel);
            app.AnalysismethodLabel.Text = '*?* Analysis method';
            app.AnalysismethodDropDown = uidropdown(app.AnalysissettingsPanel);
            app.AnalysismethodDropDown.Items = {'Integrated impulse method', 'Interrupted noise'};
            app.AnalysismethodDropDown.Value = 'Integrated impulse method';
            app.ResultsPanel = uipanel(app.GridLayout3);
            app.ResultsPanel.Title = 'Results';
            app.GridLayout18 = uigridlayout(app.ResultsPanel);
            app.UITableApparentSoundReductionIndexRw = uitable(app.GridLayout18);
            app.UITableApparentSoundReductionIndexRw.ColumnName = {'1/3 octave'; 'Rw30'; 'Rw20'; 'RwManual'};
            app.UITableApparentSoundReductionIndexRw.ColumnRearrangeable = 'on';
            app.UITableApparentSoundReductionIndexRw.RowName = {};
            app.ApparentsoundreductionindexLabel = uilabel(app.GridLayout18);
            app.ApparentsoundreductionindexLabel.Text = 'Apparent sound reduction index';
            app.Label = uilabel(app.GridLayout18);
            app.Label.Text = {'1. Determine the lowest octave band with correctly calculated reverberation time'; '2.'; '3.'; '...'};
            app.DatainterpretationguidanceLabel = uilabel(app.GridLayout18);
            app.DatainterpretationguidanceLabel.Text = 'Data interpretation guidance';
            app.RoomparametersPanel = uipanel(app.GridLayout3);
            app.RoomparametersPanel.Title = 'Room parameters';
            app.SourceroomenergyleveldBEditFieldLabel = uilabel(app.RoomparametersPanel);
            app.SourceroomenergyleveldBEditFieldLabel.Text = 'Source room energy level [dB]';
            app.SourceroomenergyleveldBEditField = uieditfield(app.RoomparametersPanel, 'numeric');
            app.SourceroomenergyleveldBEditField.Limits = [10 Inf];
            app.SourceroomenergyleveldBEditField.Value = 113;
            app.ReceivingroomenergyleveldBLabel = uilabel(app.RoomparametersPanel);
            app.ReceivingroomenergyleveldBLabel.Text = 'Receiving room energy level [dB]';
            app.ReceivingroomenergyleveldBEditField = uieditfield(app.RoomparametersPanel, 'numeric');
            app.ReceivingroomenergyleveldBEditField.Limits = [1 Inf];
            app.ReceivingroomenergyleveldBEditField.Value = 82;
            app.Receivingroomvolumem3EditFieldLabel = uilabel(app.RoomparametersPanel);
            app.Receivingroomvolumem3EditFieldLabel.Text = 'Receiving room volume [m^3]';
            app.Receivingroomvolumem3EditField = uieditfield(app.RoomparametersPanel, 'numeric');
            app.Receivingroomvolumem3EditField.Limits = [0 Inf];
            app.Receivingroomvolumem3EditField.Value = 20.24;
            app.Partitionaream2EditFieldLabel = uilabel(app.RoomparametersPanel);
            app.Partitionaream2EditFieldLabel.Text = 'Partition area [m^2]';
            app.Partitionaream2EditField = uieditfield(app.RoomparametersPanel, 'numeric');
            app.Partitionaream2EditField.Limits = [1 Inf];
            app.Partitionaream2EditField.Value = 6.66;
            app.ReverberanttimeTab = uitab(app.TabGroup);
            app.ReverberanttimeTab.Title = 'Reverberant time';
            app.RT11bandTab = uitab(app.TabGroup);
            app.RT11bandTab.Title = 'RT 1/1 band';
            app.RT13octavebandTab = uitab(app.TabGroup);
            app.RT13octavebandTab.Title = 'RT 1/3 octave band';
            app.GridLayout19 = uigridlayout(app.RT13octavebandTab);
            app.UIAxesEDCBI = uiaxes(app.GridLayout19);
            title(app.UIAxesEDCBI, 'EDC BI ')
            xlabel(app.UIAxesEDCBI, 'X')
            ylabel(app.UIAxesEDCBI, 'Y')
            zlabel(app.UIAxesEDCBI, 'Z')
            app.AnalysisfileDropDownLabel = uilabel(app.GridLayout19);
            app.AnalysisfileDropDownLabel.Text = 'Analysis file';
            app.AnalysisfileDropDown = uidropdown(app.GridLayout19);
            app.AnalysisfileDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisfileDropDownValueChanged, true);
            app.octavebandDropDownLabel = uilabel(app.GridLayout19);
            app.octavebandDropDownLabel.Text = '1/3 octave band';
            app.octavebandDropDown = uidropdown(app.GridLayout19);
            app.octavebandDropDown.ValueChangedFcn = createCallbackFcn(app, @octavebandDropDownValueChanged, true);
            app.MicrophoneDropDownLabel = uilabel(app.GridLayout19);
            app.MicrophoneDropDownLabel.Text = 'Microphone';
            app.MicrophoneDropDown = uidropdown(app.GridLayout19);
            app.MicrophoneDropDown.ValueChangedFcn = createCallbackFcn(app, @MicrophoneDropDownValueChanged, true);
            app.StartSliderLabel = uilabel(app.GridLayout19);
            app.StartSliderLabel.Text = 'Start';
            app.StartSlider = uislider(app.GridLayout19);
            app.StartSlider.ValueChangedFcn = createCallbackFcn(app, @StartSliderValueChanged, true);
            app.StartSlider.ValueChangingFcn = createCallbackFcn(app, @StartSliderValueChanging, true);
            app.EndSliderLabel = uilabel(app.GridLayout19);
            app.EndSliderLabel.Text = 'End';
            app.EndSlider = uislider(app.GridLayout19);
            app.EndSlider.ValueChangedFcn = createCallbackFcn(app, @EndSliderValueChanged, true);
            app.EndSlider.ValueChangingFcn = createCallbackFcn(app, @EndSliderValueChanging2, true);
            app.EndSlider.Value = 32;
            app.UITableOneThirdOctaveRT = uitable(app.GridLayout19);
            app.UITableOneThirdOctaveRT.ColumnName = {'1/3 octave'; 'RT30'; 'RT20'; 'RT (manual)'};
            app.UITableOneThirdOctaveRT.RowName = {};
            app.ReverberationtimesLabel = uilabel(app.GridLayout19);
            app.ReverberationtimesLabel.Text = 'Reverberation time [s]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReverberantTime

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end